classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        LeftPanel            matlab.ui.container.Panel
        WybierzzdjcieButton  matlab.ui.control.Button
        ObliczprzynalenoznakudojednejzgrupButton  matlab.ui.control.Button
        RightPanel           matlab.ui.container.Panel
        Image                matlab.ui.control.Image
        NawybranymobraziezduymprawdopodobiestwemznajdujesiznakLabel  matlab.ui.control.Label
        Label                matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: WybierzzdjcieButton
        function WybierzzdjcieButtonPushed(app, event)
            [filename pathname]=uigetfile({'*.png';'*.jpg'},'Wybierz obraz');
            myfile=strcat(pathname,filename);
            app.Image.ImageSource = myfile;
        end

        % Button pushed function: 
        % ObliczprzynalenoznakudojednejzgrupButton
        function ObliczprzynalenoznakudojednejzgrupButtonPushed(app, event)
            result = main(app.Image.ImageSource);
            s = size(result);
            switch s(1)
                case 1
                    app.Label.Text = {result(1,:)};
                case 2
                    app.Label.Text = {result(1,:); result(2,:)};
                case 3
                    app.Label.Text = {result(1,:); result(2,:); result(3,:)};
                case 4
                    app.Label.Text = {result(1,:); result(2,:); result(3,:); result(4,:)};
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {216, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.WybierzzdjcieButton = uibutton(app.LeftPanel, 'push');
            app.WybierzzdjcieButton.ButtonPushedFcn = createCallbackFcn(app, @WybierzzdjcieButtonPushed, true);
            app.WybierzzdjcieButton.Text = {'Wybierz zdjęcie'; ''};
            app.ObliczprzynalenoznakudojednejzgrupButton = uibutton(app.LeftPanel, 'push');
            app.ObliczprzynalenoznakudojednejzgrupButton.ButtonPushedFcn = createCallbackFcn(app, @ObliczprzynalenoznakudojednejzgrupButtonPushed, true);
            app.ObliczprzynalenoznakudojednejzgrupButton.Text = {'Oblicz przynależność znaku do jednej z grup'; ''};
            app.RightPanel = uipanel(app.GridLayout);
            app.Image = uiimage(app.RightPanel);
            app.NawybranymobraziezduymprawdopodobiestwemznajdujesiznakLabel = uilabel(app.RightPanel);
            app.NawybranymobraziezduymprawdopodobiestwemznajdujesiznakLabel.Text = 'Na wybranym obrazie z dużym prawdopodobieństwem znajduje się znak:';
            app.Label = uilabel(app.RightPanel);
            app.Label.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end